classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        boardPanel        matlab.ui.container.Panel
        GridLayout        matlab.ui.container.GridLayout
        Panel_Start       matlab.ui.container.Panel
        GridLayout204     matlab.ui.container.GridLayout
        Button_4jucatori  matlab.ui.control.Button
        Button_3jucatori  matlab.ui.control.Button
        Button_2jucatori  matlab.ui.control.Button
        StartButton       matlab.ui.control.Button
        Image41           matlab.ui.control.Image
        Image35           matlab.ui.control.Image
        Image40           matlab.ui.control.Image
        Image39           matlab.ui.control.Image
        Image38           matlab.ui.control.Image
        Image37           matlab.ui.control.Image
        Image36           matlab.ui.control.Image
        Image34           matlab.ui.control.Image
        Image33           matlab.ui.control.Image
        Image31           matlab.ui.control.Image
        GridLayout202     matlab.ui.container.GridLayout
        Image28           matlab.ui.control.Image
        Image27           matlab.ui.control.Image
        Image26           matlab.ui.control.Image
        Jucator           matlab.ui.control.NumericEditField
        Image25           matlab.ui.control.Image
        poz16Panel        matlab.ui.container.Panel
        GridLayout17      matlab.ui.container.GridLayout
        Image19           matlab.ui.control.Image
        TextArea          matlab.ui.control.TextArea
        poz19Panel        matlab.ui.container.Panel
        GridLayout201     matlab.ui.container.GridLayout
        Image22           matlab.ui.control.Image
        poz18Panel        matlab.ui.container.Panel
        GridLayout191     matlab.ui.container.GridLayout
        Image21           matlab.ui.control.Image
        poz17Panel        matlab.ui.container.Panel
        GridLayout18      matlab.ui.container.GridLayout
        Image20           matlab.ui.control.Image
        poz15Panel        matlab.ui.container.Panel
        GridLayout16      matlab.ui.container.GridLayout
        Image18           matlab.ui.control.Image
        poz14Panel        matlab.ui.container.Panel
        GridLayout15      matlab.ui.container.GridLayout
        Image17           matlab.ui.control.Image
        poz13Panel        matlab.ui.container.Panel
        GridLayout14      matlab.ui.container.GridLayout
        Image16           matlab.ui.control.Image
        poz12Panel        matlab.ui.container.Panel
        GridLayout13      matlab.ui.container.GridLayout
        Image15           matlab.ui.control.Image
        poz11Panel        matlab.ui.container.Panel
        GridLayout12      matlab.ui.container.GridLayout
        Image14           matlab.ui.control.Image
        poz10Panel        matlab.ui.container.Panel
        GridLayout11      matlab.ui.container.GridLayout
        Image13           matlab.ui.control.Image
        poz9Panel         matlab.ui.container.Panel
        GridLayout10      matlab.ui.container.GridLayout
        Image12           matlab.ui.control.Image
        poz8Panel         matlab.ui.container.Panel
        GridLayout9       matlab.ui.container.GridLayout
        Image11           matlab.ui.control.Image
        poz7Panel         matlab.ui.container.Panel
        GridLayout8       matlab.ui.container.GridLayout
        Image10           matlab.ui.control.Image
        Panel             matlab.ui.container.Panel
        GridLayout20      matlab.ui.container.GridLayout
        Button_Raspuns4   matlab.ui.control.Button
        Button_Raspuns3   matlab.ui.control.Button
        Button_Raspuns2   matlab.ui.control.Button
        Button_Raspuns1   matlab.ui.control.Button
        Intrebare         matlab.ui.control.EditField
        Panel_16          matlab.ui.container.Panel
        GridLayout21      matlab.ui.container.GridLayout
        Image24           matlab.ui.control.Image
        Image23           matlab.ui.control.Image
        Button_Carte2     matlab.ui.control.Button
        Button_Carte1     matlab.ui.control.Button
        Button_Carte3     matlab.ui.control.Button
        poz6Panel         matlab.ui.container.Panel
        GridLayout7       matlab.ui.container.GridLayout
        Image9            matlab.ui.control.Image
        poz5Panel         matlab.ui.container.Panel
        GridLayout6       matlab.ui.container.GridLayout
        Image8            matlab.ui.control.Image
        poz4Panel         matlab.ui.container.Panel
        GridLayout5       matlab.ui.container.GridLayout
        Image7            matlab.ui.control.Image
        poz3Panel         matlab.ui.container.Panel
        GridLayout4       matlab.ui.container.GridLayout
        Image6            matlab.ui.control.Image
        poz2Panel         matlab.ui.container.Panel
        GridLayout3       matlab.ui.container.GridLayout
        Image5            matlab.ui.control.Image
        poz1Panel         matlab.ui.container.Panel
        GridLayout2       matlab.ui.container.GridLayout
        Image29           matlab.ui.control.Image
        Panel_15          matlab.ui.container.Panel
        GridLayout203     matlab.ui.container.GridLayout
        GridLayout1       matlab.ui.container.GridLayout
        Button_4          matlab.ui.control.Button
        Button_3          matlab.ui.control.Button
        Button_2          matlab.ui.control.Button
        Button            matlab.ui.control.Button
        Image30           matlab.ui.control.Image
    end

    
    properties (Access = public)
         intrebari1
         intrebari2
         intrebari3
         i
         raspunsuri1
         raspunsuri2
         raspunsuri3
         j
         butoane
         k
         raspunsuricorecte
         corect
         panels
         A
         B
         C
         D
         nr
         nr_jucatori

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.intrebari1=["Care este capitala Romaniei?", "Care este cea mai mare tara din lume?", "Care este cea mai vorbita limba din lume?","Cati ani a durat Razboiul de 100 de ani?","Care este a treia nota muzicala?","Cine a pictat Mona Lisa?", "Care este a cincea planeta de la Soare?","Cate laturi egale are un triunghi isoscel?","Cine a luat titlul de campioana mondiala la tenis in 2018?", "Cat de des sunt organizate jocurile olimpice?"];    
            app.raspunsuri1=["Bucuresti","Madrid","Budapesta","Milano","China", "Spania", "Rusia","Canada","engleza", "spaniola", "mandarina","italiana", "102", "100","120", "116","Do","La", "Mi", "Fa","Leonardo Da Vinci", "Pablo Picasso","Vincent van Gogh","Nicolae Grogorescu","Jupiter", "Marte", "Pamant","Saturn","0", "2", "3", "4","Maria Sarapova", "Serena Williams", "Simona Halep","Ana Ivanovic", "o data la 3 ani","o data la 5 ani","o data la 4 ani","in fiecare an",];
            
            app.intrebari2=["Cine a inventat telefonul?","Ce organ produce insulina?"," Care este unitatea de măsură pentru forța în Sistemul Internațional?","Cat traieste o matca?","Care este cel mai lung fluviu din lume?","Câte state are Statele Unite ale Americii?","Cu ce scriitor l-ar fi înșelat Veronica Micle pe Mihai Eminescu?","Câte premii Oscar are Leonardo di Caprio?","Câți fotbaliști are o echipă de fotbal în teren la startul meciului?","Cum se numește singurul lac vulcanic din România?","Ce altitudine are Vârful Moldoveanu?"];
            app.raspunsuri2=["Alexander Graham Bell","John Napier","Alfred Nobel","Isaac Newton","ficatul","rinichiul","pancreasul","splina","candela", "kilogram", "newton","amper","2 ani", "30 de zile", "20 ani", "6 ani","Dunarea","Nilul", "Amazonul", "Volga","49","50","51","61","Ion Luca Caragiale", "Ion Creanga","Nichita Stanescu","Vasile Alecsandri","2","5","1","0","10","6","21","11","Lacul Rosu","Lacul Sfanta Ana","Lacul Razim", "Lacul Ochiul Boului","2554m","3650m","2544m","2342m"];
            
            app.intrebari3=["Care este singura țară din lume cu trei capitale?","Ce tip de celule sanguine sunt responsabile pentru coagularea sângelui?","Care este formula chimica a apei?","Cine a scris versurile imnului Romaniei?","În ce an a fost fondată Organizația Națiunilor Unite?","Cine a scris „Odiseea”?","Care este cel mai adânc lac din lume?","Care este a saptea arta?","Care este viteza luminii?","Cate elemente are tabelul periodic?"];
            app.raspunsuri3=["Africa de Nord", "SUA", "Africa de Sud", "EAU","leucocite", "trombocite","eritrocite","globule albe","HO2","HO","H2O","OH2","Popescu Iulian","Marius Andrei","Muresan Andrei","Radu Paraschivescu","1955","1845","1950","1945","Aristotel","Homer","Galenus","Feodor Dostoievski","Lacul Baikal","Lacul Victoria","Lacul Malawi","Lacul Rosu","pictura","poezia","cinematografia","sculptura","300.000km/s","300.000km/h","150.000km/s","700.000km/h","54","45","118","20"];

            app.raspunsuricorecte=["Bucuresti","Rusia","mandarina","116", "Mi","Leonardo Da Vinci","Jupiter","2","Simona Halep","o data la 4 ani","Alexander Graham Bell","pancreasul","newton","6 ani","Amazonul","50","Ion Luca Caragiale","1","11","Lacul Sfanta Ana","2544m","Africa de Sud","trombocite","H2O","Muresan Andrei","1945","Homer","Lacul Baikal","cinematografia","300.000km/s","118"];
   

            app.panels(2)=app.GridLayout2;
            app.panels(3)=app.GridLayout3;
            app.panels(4)=app.GridLayout4;
            app.panels(5)=app.GridLayout5;
            app.panels(6)=app.GridLayout6;
            app.panels(7)=app.GridLayout7;
            app.panels(8)=app.GridLayout8;
            app.panels(9)=app.GridLayout9;
            app.panels(10)=app.GridLayout10;
            app.panels(11)=app.GridLayout11;
            app.panels(12)=app.GridLayout12;
            app.panels(13)=app.GridLayout13;
            app.panels(14)=app.GridLayout14;
            app.panels(15)=app.GridLayout15;
            app.panels(16)=app.GridLayout16;
            app.panels(17)=app.GridLayout17;
            app.panels(18)=app.GridLayout18;
            app.panels(19)=app.GridLayout191;
            app.panels(20)=app.GridLayout201;
            app.panels(21)=app.GridLayout21;
            

            app.A=1;
            app.B=1;
            app.C=1;
            app.D=1;


        end

        % Button pushed function: Button_Raspuns3
        function Button_Raspuns3Pushed(app, event)
          
            for l=1:31
                if app.Button_Raspuns3.Text== app.raspunsuricorecte(l)
                    app.Button_Raspuns3.BackgroundColor="green";
                    app.corect=1;
                    break
                else
                    app.Button_Raspuns3.BackgroundColor="red";
                    app.corect=0;

                end
            end
            if app.corect==1
                if app.Jucator.Value==1
                    
                    if app.A+app.nr<21
                        app.A=app.A+app.nr;
                    else
                        app.A=21;
                        app.Image31.Visible="on";
                        
                    end
                    app.Button.Parent=app.panels(app.A);
                end

                if app.Jucator.Value==2
                    
                    if app.B+app.nr<21
                        app.B=app.B+app.nr;
                    else
                        app.B=21;
                        app.Image31.Visible="on";
                    end
                    app.Button_2.Parent=app.panels(app.B);
                   
                end

                if app.Jucator.Value==3
                    
                   if app.C+app.nr<21
                        app.C=app.C+app.nr;
                    else
                        app.C=21;
                        app.Image31.Visible="on";
                    end
                    app.Button_3.Parent=app.panels(app.C);
                   
                end
                
                if app.Jucator.Value==4
                    
                     if app.D+app.nr<21
                        app.D=app.D+app.nr;
                    else
                        app.D=20;
                        app.Image31.Visible="on";
                    end
                    app.Button_4.Parent=app.panels(app.D);
                   
                end
                   
            end
                try
                    pause(2);
                catch
                end
                app.Intrebare.Value="Alegeti o carte!";
                app.Intrebare.FontSize=34;
                app.Button_Raspuns1.Text="";
                app.Button_Raspuns2.Text="";
                app.Button_Raspuns3.Text="";
                app.Button_Raspuns4.Text="";
                app.Button_Raspuns1.BackgroundColor="0.93,0.42,0.05";
                app.Button_Raspuns2.BackgroundColor="0.93,0.42,0.05";
                app.Button_Raspuns3.BackgroundColor="0.93,0.42,0.05";
                app.Button_Raspuns4.BackgroundColor="0.93,0.42,0.05";

            
             if app.Jucator.Value==app.nr_jucatori
             app.Jucator.Value=1;
            else
             app.Jucator.Value=app.Jucator.Value+1;
             end   
             if app.Jucator.Value==1
                app.Image25.Visible="on";
                app.Image26.Visible="off";
                app.Image27.Visible="off";
                app.Image28.Visible="off";
            end

            if app.Jucator.Value==2
                app.Image25.Visible="off";
                app.Image26.Visible="on";
                app.Image27.Visible="off";
                app.Image28.Visible="off";
            end

            if app.Jucator.Value==3
                app.Image25.Visible="off";
                app.Image26.Visible="off";
                app.Image27.Visible="on";
                app.Image28.Visible="off";
            end

            if app.Jucator.Value==4
                app.Image25.Visible="off";
                app.Image26.Visible="off";
                app.Image27.Visible="off";
                app.Image28.Visible="on";
            end
             
            app.Button_Carte1.Visible='on';
            app.Button_Carte2.Visible='on';
            app.Button_Carte3.Visible='on';

     
        end

        % Button pushed function: Button_Raspuns4
        function Button_Raspuns4Pushed(app, event)
      
            for l=1:31
                if app.Button_Raspuns4.Text== app.raspunsuricorecte(l)
                    app.Button_Raspuns4.BackgroundColor="green";
                    app.corect=1;
                    break
                else
                    app.Button_Raspuns4.BackgroundColor="red";
                    app.corect=0;

                end
            end
            if app.corect==1
                if app.Jucator.Value==1
                    
                    if app.A+app.nr<21
                        app.A=app.A+app.nr;
                    else
                        app.A=21;
                        app.Image31.Visible="on";
                    end
                    app.Button.Parent=app.panels(app.A);
                end

                if app.Jucator.Value==2
                    
                    if app.B+app.nr<21
                        app.B=app.B+app.nr;
                    else
                        app.B=21;
                        app.Image31.Visible="on";
                    end
                    app.Button_2.Parent=app.panels(app.B);
                   
                end

                if app.Jucator.Value==3
                    
                   if app.C+app.nr<21
                        app.C=app.C+app.nr;
                    else
                        app.C=21;
                        app.Image31.Visible="on";
                    end
                    app.Button_3.Parent=app.panels(app.C);
                   
                end

                if app.Jucator.Value==4
                    
                     if app.D+app.nr<21
                        app.D=app.D+app.nr;
                    else
                        app.D=20;
                        app.Image31.Visible="on";
                    end
                    app.Button_4.Parent=app.panels(app.D);
                   
                end
                   
            end
                try
                    pause(2);
                catch
                end
                app.Intrebare.Value="Alegeti o carte!";
                app.Intrebare.FontSize=34;
                app.Button_Raspuns1.Text="";
                app.Button_Raspuns2.Text="";
                app.Button_Raspuns3.Text="";
                app.Button_Raspuns4.Text="";
                app.Button_Raspuns1.BackgroundColor="0.93,0.42,0.05";
                app.Button_Raspuns2.BackgroundColor="0.93,0.42,0.05";
                app.Button_Raspuns3.BackgroundColor="0.93,0.42,0.05";
                app.Button_Raspuns4.BackgroundColor="0.93,0.42,0.05";

             
             if app.Jucator.Value==app.nr_jucatori
             app.Jucator.Value=1;
            else
             app.Jucator.Value=app.Jucator.Value+1;
             end   
             if app.Jucator.Value==1
                app.Image25.Visible="on";
                app.Image26.Visible="off";
                app.Image27.Visible="off";
                app.Image28.Visible="off";
            end

            if app.Jucator.Value==2
                app.Image25.Visible="off";
                app.Image26.Visible="on";
                app.Image27.Visible="off";
                app.Image28.Visible="off";
            end

            if app.Jucator.Value==3
                app.Image25.Visible="off";
                app.Image26.Visible="off";
                app.Image27.Visible="on";
                app.Image28.Visible="off";
            end

            if app.Jucator.Value==4
                app.Image25.Visible="off";
                app.Image26.Visible="off";
                app.Image27.Visible="off";
                app.Image28.Visible="on";
            end
             app.Button_Carte1.Visible='on';
            app.Button_Carte2.Visible='on';
            app.Button_Carte3.Visible='on';

        end

        % Callback function: Button_Carte1, Intrebare, Jucator
        function Button_Carte1Pushed(app, event)
            
            app.i=randi([1 9], 1, 1);
            app.Intrebare.Value=app.intrebari1(app.i);
            app.Intrebare.FontSize=14;
            app.j=(app.i-1)*4+1;
            app.k=1;
            while((app.j<(app.i-1)*4+5) && app.k<5)
                if app.k==1
                app.Button_Raspuns1.Text=app.raspunsuri1(app.j);
                end
                 if app.k==2
                app.Button_Raspuns2.Text=app.raspunsuri1(app.j);
                 end
                  if app.k==3
                app.Button_Raspuns3.Text=app.raspunsuri1(app.j);
                  end
                   if app.k==4
                app.Button_Raspuns4.Text=app.raspunsuri1(app.j);
                   end
                app.j=app.j+1;
                app.k=app.k+1;
            end
            app.Button_Carte1.Visible='off';
            app.Button_Carte2.Visible='off';
            app.Button_Carte3.Visible='off';
            app.nr=1;


        end

        % Button pushed function: Button_Carte2
        function Button_Carte2Pushed(app, event)
            
            app.i=randi([1 11], 1, 1);
            app.Intrebare.Value=app.intrebari2(app.i);
            app.Intrebare.FontSize=14;
            app.j=(app.i-1)*4+1;
            app.k=1;
            while((app.j<(app.i-1)*4+5) && app.k<5)
                if app.k==1
                app.Button_Raspuns1.Text=app.raspunsuri2(app.j);
                end
                 if app.k==2
                app.Button_Raspuns2.Text=app.raspunsuri2(app.j);
                 end
                  if app.k==3
                app.Button_Raspuns3.Text=app.raspunsuri2(app.j);
                  end
                   if app.k==4
                app.Button_Raspuns4.Text=app.raspunsuri2(app.j);
                   end
                app.j=app.j+1;
                app.k=app.k+1;
            end
            app.Button_Carte1.Visible='off';
            app.Button_Carte2.Visible='off';
            app.Button_Carte3.Visible='off';
            app.nr=2;
        end

        % Button pushed function: Button_Carte3
        function Button_Carte3Pushed(app, event)
            
            app.i=randi([1 10], 1, 1);
            app.Intrebare.Value=app.intrebari3(app.i);
            app.Intrebare.FontSize=14;
            app.j=(app.i-1)*4+1;
            app.k=1;
            while((app.j<(app.i-1)*4+5) && app.k<5)
                if app.k==1
                app.Button_Raspuns1.Text=app.raspunsuri3(app.j);
                end
                 if app.k==2
                app.Button_Raspuns2.Text=app.raspunsuri3(app.j);
                 end
                  if app.k==3
                app.Button_Raspuns3.Text=app.raspunsuri3(app.j);
                  end
                   if app.k==4
                app.Button_Raspuns4.Text=app.raspunsuri3(app.j);
                   end
                app.j=app.j+1;
                app.k=app.k+1;
            end
            app.Button_Carte1.Visible='off';
            app.Button_Carte2.Visible='off';
            app.Button_Carte3.Visible='off';
            app.nr=3;
        end

        % Button pushed function: Button_Raspuns2
        function Button_Raspuns2Pushed(app, event)
            for l=1:31
                if app.Button_Raspuns2.Text== app.raspunsuricorecte(l)
                    app.Button_Raspuns2.BackgroundColor="green";
                    app.corect=1;
                    break
                else
                    app.Button_Raspuns2.BackgroundColor="red";
                    app.corect=0;

                end
            end
            if app.corect==1
                if app.Jucator.Value==1
                    
                    if app.A+app.nr<21
                        app.A=app.A+app.nr;
                    else
                        app.A=21;
                        app.Image31.Visible="on";
                    end
                    app.Button.Parent=app.panels(app.A);
                end

                if app.Jucator.Value==2
                    
                    if app.B+app.nr<21
                        app.B=app.B+app.nr;
                    else
                        app.B=21;
                        app.Image31.Visible="on";
                    end
                    app.Button_2.Parent=app.panels(app.B);
                   
                end

                if app.Jucator.Value==3
                    
                   if app.C+app.nr<21
                        app.C=app.C+app.nr;
                    else
                        app.C=21;
                        app.Image31.Visible="on";
                    end
                    app.Button_3.Parent=app.panels(app.C);
                   
                end

                if app.Jucator.Value==4
                    
                     if app.D+app.nr<21
                        app.D=app.D+app.nr;
                    else
                        app.D=20;
                        app.Image31.Visible="on";
                    end
                    app.Button_4.Parent=app.panels(app.D);
                   
                end
                   
            end

                try
                    pause(2);
                catch
                end
                app.Intrebare.Value="Alegeti o carte!";
                app.Intrebare.FontSize=34;
                app.Button_Raspuns1.Text="";
                app.Button_Raspuns2.Text="";
                app.Button_Raspuns3.Text="";
                app.Button_Raspuns4.Text="";
                app.Button_Raspuns1.BackgroundColor="0.93,0.42,0.05";
                app.Button_Raspuns2.BackgroundColor="0.93,0.42,0.05";
                app.Button_Raspuns3.BackgroundColor="0.93,0.42,0.05";
                app.Button_Raspuns4.BackgroundColor="0.93,0.42,0.05";


             if app.Jucator.Value==app.nr_jucatori
             app.Jucator.Value=1;
            else
             app.Jucator.Value=app.Jucator.Value+1;
             end   
             if app.Jucator.Value==1
                app.Image25.Visible="on";
                app.Image26.Visible="off";
                app.Image27.Visible="off";
                app.Image28.Visible="off";
            end

            if app.Jucator.Value==2
                app.Image25.Visible="off";
                app.Image26.Visible="on";
                app.Image27.Visible="off";
                app.Image28.Visible="off";
            end

            if app.Jucator.Value==3
                app.Image25.Visible="off";
                app.Image26.Visible="off";
                app.Image27.Visible="on";
                app.Image28.Visible="off";
            end

            if app.Jucator.Value==4
                app.Image25.Visible="off";
                app.Image26.Visible="off";
                app.Image27.Visible="off";
                app.Image28.Visible="on";
            end
            app.Button_Carte1.Visible='on';
            app.Button_Carte2.Visible='on';
            app.Button_Carte3.Visible='on';
        end

        % Button pushed function: Button_Raspuns1
        function Button_Raspuns1Pushed(app, event)
             
            for l=1:31
                if app.Button_Raspuns1.Text== app.raspunsuricorecte(l)
                    app.Button_Raspuns1.BackgroundColor="green";
                    app.corect=1;
                    break;
                else
                    app.Button_Raspuns1.BackgroundColor="red";
                    app.corect=0;

                end
            end
            if app.corect==1
                if app.Jucator.Value==1
                    
                    if app.A+app.nr<21
                        app.A=app.A+app.nr;
                    else
                        app.A=21;
                        app.Image31.Visible="on";
                        
                    end
                    app.Button.Parent=app.panels(app.A);
                end

                if app.Jucator.Value==2
                    
                    if app.B+app.nr<21
                        app.B=app.B+app.nr;
                    else
                        app.B=21;
                        app.Image31.Visible="on";
                    end
                    app.Button_2.Parent=app.panels(app.B);
                   
                end

                if app.Jucator.Value==3
                    
                   if app.C+app.nr<21
                        app.C=app.C+app.nr;
                    else
                        app.C=21;
                        app.Image31.Visible="on";
                    end
                    app.Button_3.Parent=app.panels(app.C);
                   
                end

                if app.Jucator.Value==4
                    
                     if app.D+app.nr<21
                        app.D=app.D+app.nr;
                    else
                        app.D=21;
                        app.Image31.Visible="on";
                    end
                    app.Button_4.Parent=app.panels(app.D);
                   
                end
       
            end
                try
                    pause(2);
                catch
                end
                app.Intrebare.Value="Alegeti o carte!";
                app.Intrebare.FontSize=34;
                app.Button_Raspuns1.Text="";
                app.Button_Raspuns2.Text="";
                app.Button_Raspuns3.Text="";
                app.Button_Raspuns4.Text="";
                app.Button_Raspuns1.BackgroundColor="0.93,0.42,0.05";
                app.Button_Raspuns2.BackgroundColor="0.93,0.42,0.05";
                app.Button_Raspuns3.BackgroundColor="0.93,0.42,0.05";
                app.Button_Raspuns4.BackgroundColor="0.93,0.42,0.05";
             if app.Jucator.Value==app.nr_jucatori
             app.Jucator.Value=1;
            else
             app.Jucator.Value=app.Jucator.Value+1;
             end   

            if app.Jucator.Value==1
                app.Image25.Visible="on";
                app.Image26.Visible="off";
                app.Image27.Visible="off";
                app.Image28.Visible="off";
            end

            if app.Jucator.Value==2
                app.Image25.Visible="off";
                app.Image26.Visible="on";
                app.Image27.Visible="off";
                app.Image28.Visible="off";
            end

            if app.Jucator.Value==3
                app.Image25.Visible="off";
                app.Image26.Visible="off";
                app.Image27.Visible="on";
                app.Image28.Visible="off";
            end

            if app.Jucator.Value==4
                app.Image25.Visible="off";
                app.Image26.Visible="off";
                app.Image27.Visible="off";
                app.Image28.Visible="on";
            end
             
            app.Button_Carte1.Visible='on';
            app.Button_Carte2.Visible='on';
            app.Button_Carte3.Visible='on';
            

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Visible="off";
            app.Button_2jucatori.Visible="on";
            app.Button_3jucatori.Visible="on";
            app.Button_4jucatori.Visible="on";
        end

        % Button pushed function: Button_2jucatori
        function Button_2jucatoriPushed(app, event)
            app.nr_jucatori=2;
            app.Panel_Start.Visible="off";
            app.Button_3.Visible="off";
            app.Button_4.Visible="off";
        end

        % Button pushed function: Button_3jucatori
        function Button_3jucatoriPushed(app, event)
            app.nr_jucatori=3;
            app.Panel_Start.Visible="off";
            app.Button_4.Visible="off";
        end

        % Button pushed function: Button_4jucatori
        function Button_4jucatoriPushed(app, event)
            app.nr_jucatori=4;
            app.Panel_Start.Visible="off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.boardPanel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.boardPanel);
            app.Panel_15 = uipanel(app.GridLayout);
            app.GridLayout203 = uigridlayout(app.Panel_15);
            app.Image30 = uiimage(app.GridLayout203);
            app.Image30.ImageSource = fullfile(pathToMLAPP, 'cerc2.png');
            app.GridLayout1 = uigridlayout(app.GridLayout203);
            app.Button = uibutton(app.GridLayout1, 'push');
            app.Button.Icon = fullfile(pathToMLAPP, 'rosu.png');
            app.Button.Text = '';
            app.Button_2 = uibutton(app.GridLayout1, 'push');
            app.Button_2.Icon = fullfile(pathToMLAPP, 'galben.png');
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.GridLayout1, 'push');
            app.Button_3.Icon = fullfile(pathToMLAPP, 'mov.png');
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.GridLayout1, 'push');
            app.Button_4.Icon = fullfile(pathToMLAPP, 'verde.png');
            app.Button_4.Text = '';
            app.poz1Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.poz1Panel);
            app.Image29 = uiimage(app.GridLayout2);
            app.Image29.ImageSource = fullfile(pathToMLAPP, '1-removebg-preview.png');
            app.poz2Panel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.poz2Panel);
            app.Image5 = uiimage(app.GridLayout3);
            app.Image5.ImageSource = fullfile(pathToMLAPP, '_2.png');
            app.poz3Panel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.poz3Panel);
            app.Image6 = uiimage(app.GridLayout4);
            app.Image6.ImageSource = fullfile(pathToMLAPP, '3.3.png');
            app.poz4Panel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.poz4Panel);
            app.Image7 = uiimage(app.GridLayout5);
            app.Image7.ImageSource = fullfile(pathToMLAPP, '1-removebg-preview (1).png');
            app.poz5Panel = uipanel(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.poz5Panel);
            app.Image8 = uiimage(app.GridLayout6);
            app.Image8.ImageSource = fullfile(pathToMLAPP, '1-removebg-preview (2).png');
            app.poz6Panel = uipanel(app.GridLayout);
            app.GridLayout7 = uigridlayout(app.poz6Panel);
            app.Image9 = uiimage(app.GridLayout7);
            app.Image9.ImageSource = fullfile(pathToMLAPP, '1-removebg-preview (3).png');
            app.Button_Carte3 = uibutton(app.GridLayout, 'push');
            app.Button_Carte3.ButtonPushedFcn = createCallbackFcn(app, @Button_Carte3Pushed, true);
            app.Button_Carte3.Icon = fullfile(pathToMLAPP, '3.png');
            app.Button_Carte3.Text = '';
            app.Button_Carte1 = uibutton(app.GridLayout, 'push');
            app.Button_Carte1.ButtonPushedFcn = createCallbackFcn(app, @Button_Carte1Pushed, true);
            app.Button_Carte1.Icon = fullfile(pathToMLAPP, '1.png');
            app.Button_Carte1.Text = '';
            app.Button_Carte2 = uibutton(app.GridLayout, 'push');
            app.Button_Carte2.ButtonPushedFcn = createCallbackFcn(app, @Button_Carte2Pushed, true);
            app.Button_Carte2.Icon = fullfile(pathToMLAPP, '2.png');
            app.Button_Carte2.Text = '';
            app.Panel_16 = uipanel(app.GridLayout);
            app.GridLayout21 = uigridlayout(app.Panel_16);
            app.Image23 = uiimage(app.GridLayout21);
            app.Image23.ImageSource = fullfile(pathToMLAPP, '20.png');
            app.Image24 = uiimage(app.GridLayout21);
            app.Image24.ImageSource = fullfile(pathToMLAPP, 'finish.png');
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout20 = uigridlayout(app.Panel);
            app.Intrebare = uieditfield(app.GridLayout20, 'text');
            app.Intrebare.ValueChangingFcn = createCallbackFcn(app, @Button_Carte1Pushed, true);
            app.Intrebare.Value = 'Alegeti o carte!';
            app.Button_Raspuns1 = uibutton(app.GridLayout20, 'push');
            app.Button_Raspuns1.ButtonPushedFcn = createCallbackFcn(app, @Button_Raspuns1Pushed, true);
            app.Button_Raspuns1.Text = '';
            app.Button_Raspuns2 = uibutton(app.GridLayout20, 'push');
            app.Button_Raspuns2.ButtonPushedFcn = createCallbackFcn(app, @Button_Raspuns2Pushed, true);
            app.Button_Raspuns2.Text = '';
            app.Button_Raspuns3 = uibutton(app.GridLayout20, 'push');
            app.Button_Raspuns3.ButtonPushedFcn = createCallbackFcn(app, @Button_Raspuns3Pushed, true);
            app.Button_Raspuns3.Text = '';
            app.Button_Raspuns4 = uibutton(app.GridLayout20, 'push');
            app.Button_Raspuns4.ButtonPushedFcn = createCallbackFcn(app, @Button_Raspuns4Pushed, true);
            app.Button_Raspuns4.Text = '';
            app.poz7Panel = uipanel(app.GridLayout);
            app.GridLayout8 = uigridlayout(app.poz7Panel);
            app.Image10 = uiimage(app.GridLayout8);
            app.Image10.ImageSource = fullfile(pathToMLAPP, '1-removebg-preview (4).png');
            app.poz8Panel = uipanel(app.GridLayout);
            app.GridLayout9 = uigridlayout(app.poz8Panel);
            app.Image11 = uiimage(app.GridLayout9);
            app.Image11.ImageSource = fullfile(pathToMLAPP, '1__1_-removebg-preview.png');
            app.poz9Panel = uipanel(app.GridLayout);
            app.GridLayout10 = uigridlayout(app.poz9Panel);
            app.Image12 = uiimage(app.GridLayout10);
            app.Image12.ImageSource = fullfile(pathToMLAPP, '1__2_-removebg-preview.png');
            app.poz10Panel = uipanel(app.GridLayout);
            app.GridLayout11 = uigridlayout(app.poz10Panel);
            app.Image13 = uiimage(app.GridLayout11);
            app.Image13.ImageSource = fullfile(pathToMLAPP, '1__3_-removebg-preview.png');
            app.poz11Panel = uipanel(app.GridLayout);
            app.GridLayout12 = uigridlayout(app.poz11Panel);
            app.Image14 = uiimage(app.GridLayout12);
            app.Image14.ImageSource = fullfile(pathToMLAPP, '1__4_-removebg-preview.png');
            app.poz12Panel = uipanel(app.GridLayout);
            app.GridLayout13 = uigridlayout(app.poz12Panel);
            app.Image15 = uiimage(app.GridLayout13);
            app.Image15.ImageSource = fullfile(pathToMLAPP, '1__5_-removebg-preview.png');
            app.poz13Panel = uipanel(app.GridLayout);
            app.GridLayout14 = uigridlayout(app.poz13Panel);
            app.Image16 = uiimage(app.GridLayout14);
            app.Image16.ImageSource = fullfile(pathToMLAPP, '1__6_-removebg-preview.png');
            app.poz14Panel = uipanel(app.GridLayout);
            app.GridLayout15 = uigridlayout(app.poz14Panel);
            app.Image17 = uiimage(app.GridLayout15);
            app.Image17.ImageSource = fullfile(pathToMLAPP, '1__7_-removebg-preview.png');
            app.poz15Panel = uipanel(app.GridLayout);
            app.GridLayout16 = uigridlayout(app.poz15Panel);
            app.Image18 = uiimage(app.GridLayout16);
            app.Image18.ImageSource = fullfile(pathToMLAPP, '1__8_-removebg-preview.png');
            app.poz17Panel = uipanel(app.GridLayout);
            app.GridLayout18 = uigridlayout(app.poz17Panel);
            app.Image20 = uiimage(app.GridLayout18);
            app.Image20.ImageSource = fullfile(pathToMLAPP, '1__10_-removebg-preview.png');
            app.poz18Panel = uipanel(app.GridLayout);
            app.GridLayout191 = uigridlayout(app.poz18Panel);
            app.Image21 = uiimage(app.GridLayout191);
            app.Image21.ImageSource = fullfile(pathToMLAPP, '1__11_-removebg-preview.png');
            app.poz19Panel = uipanel(app.GridLayout);
            app.GridLayout201 = uigridlayout(app.poz19Panel);
            app.Image22 = uiimage(app.GridLayout201);
            app.Image22.ImageSource = fullfile(pathToMLAPP, '1__12_-removebg-preview.png');
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Value = {'Jucator'};
            app.poz16Panel = uipanel(app.GridLayout);
            app.GridLayout17 = uigridlayout(app.poz16Panel);
            app.Image19 = uiimage(app.GridLayout17);
            app.Image19.ImageSource = fullfile(pathToMLAPP, '1__9_-removebg-preview.png');
            app.GridLayout202 = uigridlayout(app.GridLayout);
            app.Image25 = uiimage(app.GridLayout202);
            app.Image25.ImageSource = fullfile(pathToMLAPP, 'rosu.png');
            app.Jucator = uieditfield(app.GridLayout202, 'numeric');
            app.Jucator.ValueChangedFcn = createCallbackFcn(app, @Button_Carte1Pushed, true);
            app.Jucator.Value = 1;
            app.Image26 = uiimage(app.GridLayout202);
            app.Image26.Visible = 'off';
            app.Image26.ImageSource = fullfile(pathToMLAPP, 'galben.png');
            app.Image27 = uiimage(app.GridLayout202);
            app.Image27.Visible = 'off';
            app.Image27.ImageSource = fullfile(pathToMLAPP, 'mov.png');
            app.Image28 = uiimage(app.GridLayout202);
            app.Image28.Visible = 'off';
            app.Image28.ImageSource = fullfile(pathToMLAPP, 'verde.png');
            app.Image31 = uiimage(app.GridLayout);
            app.Image31.Visible = 'off';
            app.Image31.ImageSource = fullfile(pathToMLAPP, 'felicitari.png');
            app.Image33 = uiimage(app.GridLayout);
            app.Image33.ImageSource = fullfile(pathToMLAPP, 'img2.png');
            app.Image34 = uiimage(app.GridLayout);
            app.Image34.ImageSource = fullfile(pathToMLAPP, 'img2.png');
            app.Image36 = uiimage(app.GridLayout);
            app.Image36.ImageSource = fullfile(pathToMLAPP, 'img2.png');
            app.Image37 = uiimage(app.GridLayout);
            app.Image37.ImageSource = fullfile(pathToMLAPP, 'img2.png');
            app.Image38 = uiimage(app.GridLayout);
            app.Image38.ImageSource = fullfile(pathToMLAPP, 'img2.png');
            app.Image39 = uiimage(app.GridLayout);
            app.Image39.ImageSource = fullfile(pathToMLAPP, 'img2.png');
            app.Image40 = uiimage(app.GridLayout);
            app.Image40.ImageSource = fullfile(pathToMLAPP, 'img2.png');
            app.Image35 = uiimage(app.GridLayout);
            app.Image35.ImageSource = fullfile(pathToMLAPP, 'img2.png');
            app.Panel_Start = uipanel(app.GridLayout);
            app.GridLayout204 = uigridlayout(app.Panel_Start);
            app.Image41 = uiimage(app.GridLayout204);
            app.Image41.ImageSource = fullfile(pathToMLAPP, 'start2.png');
            app.StartButton = uibutton(app.GridLayout204, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Button_2jucatori = uibutton(app.GridLayout204, 'push');
            app.Button_2jucatori.ButtonPushedFcn = createCallbackFcn(app, @Button_2jucatoriPushed, true);
            app.Button_2jucatori.Visible = 'off';
            app.Button_2jucatori.Text = '2 jucatori';
            app.Button_3jucatori = uibutton(app.GridLayout204, 'push');
            app.Button_3jucatori.ButtonPushedFcn = createCallbackFcn(app, @Button_3jucatoriPushed, true);
            app.Button_3jucatori.Visible = 'off';
            app.Button_3jucatori.Text = '3 jucatori';
            app.Button_4jucatori = uibutton(app.GridLayout204, 'push');
            app.Button_4jucatori.ButtonPushedFcn = createCallbackFcn(app, @Button_4jucatoriPushed, true);
            app.Button_4jucatori.Visible = 'off';
            app.Button_4jucatori.Text = '4 jucatori';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end